classdef HexControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AxNumeric_6                     matlab.ui.control.NumericEditField
        AxNumeric_5                     matlab.ui.control.NumericEditField
        AxNumeric_4                     matlab.ui.control.NumericEditField
        AxNumeric_3                     matlab.ui.control.NumericEditField
        AxNumeric_2                     matlab.ui.control.NumericEditField
        AxNumeric_1                     matlab.ui.control.NumericEditField
        YawdegEditField                 matlab.ui.control.NumericEditField
        YawdegEditFieldLabel            matlab.ui.control.Label
        PitchdegEditField               matlab.ui.control.NumericEditField
        PitchdegEditFieldLabel          matlab.ui.control.Label
        RolldegEditField                matlab.ui.control.NumericEditField
        RolldegEditFieldLabel           matlab.ui.control.Label
        ZmEditField                     matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        XmLabel                         matlab.ui.control.Label
        SaveworkspaceButton             matlab.ui.control.Button
        Ax6GaugeLabel                   matlab.ui.control.Label
        Ax6Gauge                        matlab.ui.control.LinearGauge
        Ax5GaugeLabel                   matlab.ui.control.Label
        Ax5Gauge                        matlab.ui.control.LinearGauge
        Ax4GaugeLabel                   matlab.ui.control.Label
        Ax4Gauge                        matlab.ui.control.LinearGauge
        Ax3GaugeLabel                   matlab.ui.control.Label
        Ax3Gauge                        matlab.ui.control.LinearGauge
        Ax2GaugeLabel                   matlab.ui.control.Label
        Ax2Gauge                        matlab.ui.control.LinearGauge
        Ax1GaugeLabel                   matlab.ui.control.Label
        Ax1Gauge                        matlab.ui.control.LinearGauge
        LampPanel                       matlab.ui.container.Panel
        EStopStatusLamp                 matlab.ui.control.Lamp
        EStopStatusLampLabel            matlab.ui.control.Label
        AllLimitSwitchesClearLamp       matlab.ui.control.Lamp
        AllLimitSwitchesClearLampLabel  matlab.ui.control.Label
        ReadyToRunLamp                  matlab.ui.control.Lamp
        ReadyToRunLampLabel             matlab.ui.control.Label
        MotionProfileOKLamp             matlab.ui.control.Lamp
        MotionProfileOKLampLabel        matlab.ui.control.Label
        AtHomeLamp                      matlab.ui.control.Lamp
        AtHomeLampLabel                 matlab.ui.control.Label
        PositionKnownLamp               matlab.ui.control.Lamp
        PositionKnownLampLabel          matlab.ui.control.Label
        ControllerInitializedLamp       matlab.ui.control.Lamp
        ControllerInitializedLampLabel  matlab.ui.control.Label
        QueryControllerStatusButton     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        StartupTab                      matlab.ui.container.Tab
        AngleIncrementEditField         matlab.ui.control.NumericEditField
        AngleIncrementEditFieldLabel    matlab.ui.control.Label
        JogDistancemmEditField          matlab.ui.control.NumericEditField
        JogDistancemmEditFieldLabel     matlab.ui.control.Label
        RyButton_2                      matlab.ui.control.Button
        RyButton                        matlab.ui.control.Button
        RxButton_2                      matlab.ui.control.Button
        RxButton                        matlab.ui.control.Button
        RzButton_2                      matlab.ui.control.Button
        RzButton                        matlab.ui.control.Button
        YButton_2                       matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        ReturntoHomePositionButton      matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        ControllerResponseTextArea      matlab.ui.control.TextArea
        ControllerResponseTextAreaLabel  matlab.ui.control.Label
        InitializeControllerButton      matlab.ui.control.Button
        HomingTab                       matlab.ui.container.Tab
        HomeGeneratePathButton          matlab.ui.control.Button
        GetCurrentPlatformPositionButton  matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        TestArticleSetUpTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CoordinateSystemTab             matlab.ui.container.Tab
        EnterButton                     matlab.ui.control.Button
        ZmmEditField                    matlab.ui.control.NumericEditField
        ZmmEditFieldLabel               matlab.ui.control.Label
        YmmEditField                    matlab.ui.control.NumericEditField
        YmmEditFieldLabel               matlab.ui.control.Label
        XmmEditField                    matlab.ui.control.NumericEditField
        XmmEditFieldLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MassDistributionTab             matlab.ui.container.Tab
        EnterButton_2                   matlab.ui.control.Button
        I_zzkgm2EditField               matlab.ui.control.NumericEditField
        I_zzkgm2EditFieldLabel          matlab.ui.control.Label
        I_yykgm2EditField               matlab.ui.control.NumericEditField
        I_yykgm2EditFieldLabel          matlab.ui.control.Label
        TotalMasskgEditField            matlab.ui.control.NumericEditField
        TotalMasskgLabel                matlab.ui.control.Label
        I_xxkgm2EditField               matlab.ui.control.NumericEditField
        I_xxkgm2Label                   matlab.ui.control.Label
        PlanMotionTab                   matlab.ui.container.Tab
        AnimateMotionButton             matlab.ui.control.Button
        SimulateMotionButton            matlab.ui.control.Button
        ImportMotionProfiletxtButton    matlab.ui.control.Button
        PointtoPointMotionButton        matlab.ui.control.Button
        SinusoidalMotionProfileButton   matlab.ui.control.Button
        ExecuteMotionTab                matlab.ui.container.Tab
        HaltMotionButton                matlab.ui.control.Button
        ExecuteMotionButton             matlab.ui.control.Button
        DiagnostisticsTab               matlab.ui.container.Tab
        UIAxesHex                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        hex_obj=struct(); % Hexapod object
        hex_plot3D=struct(); %Plotting object for hexapod 3D view
        hex_setup=struct(); % Structure containing platform, model mass and external forces
        hex_status=struct(); % Structure containing system status and readiness indicators;
        hex_path=struct(); % Structure containing the motion profile description
        LED_stat=struct();
        JogDist=0;
        JogAngle=0;
        dT=2^2/1000; %Timestep used by Galil;
   ActuatorPositions=cell(0);
   LampArray=cell(0);
   g=0;
   Connected=0;
    end
    
    properties (Access = private)
        LEDlist={'ControllerInitialized','PositionKnown','AtHome','MotionProfileOK','ReadyToRun','AllLimitSwitchesClear','EStopStatus'};
        Point_to_Point_app % Description
        SineSeries_app;
    end
    
    methods (Access = private)
        
        function results = UpdateLEDs(app)
            LEDlist=app.LEDlist;
            LED_stat=app.LED_stat;
            N_lamps=numel(LEDlist);
            OnColor=[0 1 0];  OffColor=[1 0 0];
            for n=1:N_lamps
                color=OnColor*LED_stat.(LEDlist{n})+OffColor*(1-LED_stat.(LEDlist{n}));
                app.([LEDlist{n} 'Lamp']).Color=color;
            end
        end        
    end
    
    methods (Access = public)
        
        function [] = Grab_Point_to_PointData(app,SegTable,MotionType)
            SegTable2=table2array(SegTable);
            N_segments=size(SegTable2,1)-1;
MasterPath=[]; MasterTime=[];
            for n=1:N_segments
Ttemp=[SegTable2(n,1):app.dT:SegTable2(n+1,1)];
temppath=[];           
for j=1:6
    switch MotionType
        case 0
                temppath(j,:)=linspace(SegTable2(n,j+1),SegTable2(n+1,j+1),length(Ttemp));
        case 1
                temppath(j,:)=squashfunction(Ttemp,SegTable2(n,j+1),SegTable2(n+1,j+1));
    end
            end
            MasterTime=[MasterTime Ttemp];
            MasterPath=[MasterPath temppath];
            end
            MasterPath
app.hex_path.T=MasterTime';
app.hex_path.pose_t=MasterPath;
app.hex_path.pose_t(4:6,:)=deg2rad(app.hex_path.pose_t(4:6,:));

        end


        function [] = Grab_SineSeriesData(app,ComponentTable,SineDuration,DatumType)
            ComponentTable2=table2array(ComponentTable);
            N_components=size(ComponentTable2,1);
 MasterTime=[];
            
MasterTime=[0:app.dT:SineDuration];
MasterPath=zeros(6,size(MasterTime,2));
for n=1:N_components
freq=ComponentTable2(n,1)*2*pi
Amptemp=[ComponentTable2(n,2:7)]'
PhaseTemp=deg2rad(ComponentTable2(n,8))
temppath=Amptemp*sin(freq*MasterTime+PhaseTemp)           

MasterPath=MasterPath+temppath;

end
ramptime=2;
N_ramp=round(ramptime/app.dT);
Ramp_Env=ones(size(MasterTime))
Ramp_Env(1:N_ramp)=linspace(0,1,N_ramp);
Ramp_Env(end-N_ramp+1:end)=linspace(1,0,N_ramp);

for j=1:6
    MasterPath(j,:)=MasterPath(j,:).*Ramp_Env;
end

MasterPath(4:6,:)=deg2rad(MasterPath(4:6,:));

if DatumType==1
    CurrentPose=hex_obj.pose;
    MasterPath=MasterPath+repmat(CurrentPose,1,length(MasterTime));
end
% switch(DatumType)
%     case 0
% end
app.hex_path.T=MasterTime';
app.hex_path.pose_t=MasterPath;
% app.hex_path.pose_t(4:6,:)=deg2rad(app.hex_path.pose_t(4:6,:));

       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.LED_stat.ControllerInitialized=0; % Description
        app.LED_stat.PositionKnown=0;
        app.LED_stat.AtHome=0;
        app.LED_stat.MotionProfileOK=0;
       app.LED_stat.ReadyToRun=0;
       app.LED_stat.AllLimitSwitchesClear=0;
        app.LED_stat.EStopStatus=0;
app.hex_path.dt=app.dT;
addpath('C:\Program Files (x86)\Galil\gclib\source\wrappers\python\')


        app.ActuatorPositions={app.Ax1Gauge;app.Ax2Gauge;app.Ax3Gauge;app.Ax4Gauge;app.Ax5Gauge;app.Ax6Gauge};
%         app.LampArray={app.EStopStatusLamp,app.AllLimitSwitchesClearLamp,app.ReadyToRunLamp,app.MotionProfileOKLamp,app.AtHomeLamp,app.PositionKnownLamp,app.ControllerInitializedLamp};
%         
        
            [app.hex_obj app.hex_setup]=InitializeHexapodObject;
            app.hex_plot3D.ax=app.UIAxesHex
            app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);
            app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
% app.ControllerInitializedLamp.Color=[0 0 0];
UpdateLEDs(app);
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            xoffset=app.XmmEditField.Value/1000;
            yoffset=app.YmmEditField.Value/1000;
            zoffset=app.ZmmEditField.Value/1000;
            app.hex_obj.r_rel=[xoffset;yoffset;zoffset];
            app.hex_obj=EffectorFromPlatformPose(app.hex_obj);
            app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);
            app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
            app.hex_obj.Home=app.hex_obj.Home_platform+app.hex_obj.r_rel;
%             app.hex_plot3D=hexapodGraphic(app.hex_obj,app.hex_plot3D);
        end

        % Button pushed function: GetCurrentPlatformPositionButton
        function GetCurrentPlatformPositionButtonPushed(app, event)
            [P_Pose camimg]=GrabCheckerboard(app.hex_obj);
            [app.hex_obj]=Platform2ModelCM(app.hex_obj,P_Pose)
            app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);
            app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
            imshow(camimg,'Parent',app.UIAxes3);
            app.LED_stat.PositionKnown=1; UpdateLEDs(app);
        end

        % Button pushed function: InitializeControllerButton
        function InitializeControllerButtonPushed(app, event)
            g=py.gclib.py;
            
            app.g=g;
            tf=isa(app.g,'py.gclib.py');


            if(tf)
                stat=0.5;
                try; 
                    g.GClose;
                    g.GOpen('192.168.42.2');
                    ss=g.GInfo;
% ss.string='TEST - GALIL OPENED'
                    app.ControllerResponseTextArea.Value=ss.string;
            app.LED_stat.ControllerInitialized=1; UpdateLEDs(app); app.Connected=1;
                catch
                    app.ControllerResponseTextArea.Value='Controller not responding'
                    app.LED_stat.ControllerInitialized=0.6; UpdateLEDs(app);
                end
            else
                app.ControllerResponseTextArea.Value='Unable to load controller Python library'
            end
            
        end

        % Button pushed function: HomeGeneratePathButton
        function HomeGeneratePathButtonPushed(app, event)
            Tf=10;
            app.hex_path.T=[0:app.dT:Tf]';
app.hex_path.dt=app.dT;
            CurrentPos=app.hex_obj.pose;
            TargetPos=[0;0;0;0;0;0];

            for j=1:6
                app.hex_path.pose_t(j,:)=squashfunction(app.hex_path.T,CurrentPos(j),TargetPos(j));
            end
% app.hex_obj.pose=TargetPos; app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup); app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);  app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
app.hex_path=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path); app.hex_path=SimulateMotionProfile_dynamics(app.hex_obj,app.hex_setup,app.hex_path,0);
[fig Check Pass]=Plot_Check_Motion(app.hex_path,app.hex_setup)
app.LED_stat.AtHome=1;app.LED_stat.AllLimitSwitchesClear=1; UpdateLEDs(app);

        end

        % Button pushed function: PointtoPointMotionButton
        function PointtoPointMotionButtonPushed(app, event)
            app.Point_to_Point_app=Point_to_Point(app,app.hex_obj)
        end

        % Button pushed function: SimulateMotionButton
        function SimulateMotionButtonPushed(app, event)
            app.hex_path=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path); app.hex_path=SimulateMotionProfile_dynamics(app.hex_obj,app.hex_setup,app.hex_path,1);
[fig Check Pass]=Plot_Check_Motion(app.hex_path,app.hex_setup)
        end

        % Button pushed function: SinusoidalMotionProfileButton
        function SinusoidalMotionProfileButtonPushed(app, event)
            app.SineSeries_app=SineSeries(app,app.hex_obj)
        end

        % Button pushed function: AnimateMotionButton
        function AnimateMotionButtonPushed(app, event)
            [vid_fig]=AnimateHexapod(app,hex_obj,hex_setup,hex_path)
            
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)

            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 app.JogDist 0 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end);app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
      
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 -app.JogDist 0 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);

app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);
            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: ReturntoHomePositionButton
        function ReturntoHomePositionButtonPushed(app, event)
           
             if(app.Connected)
            g=app.g;

            g.GCommand('SHABCEFG');
            g.GCommand('PA 0,0,0,,0,0,0')
            g.GCommand('WT250')
            g.GCommand('BGABCEFG')

             else
msgbox('Not connected to controller')
             end

             app.hex_obj.pose=[0,0,0,0,0,0]';
             app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);
        end

        % Button pushed function: RyButton_2
        function RyButton_2Pushed(app, event)
          
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 0 0 -app.JogAngle 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);

app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end  
        end

        % Button pushed function: RyButton
        function RyButtonPushed(app, event)
            
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 0 0 app.JogAngle 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: RxButton_2
        function RxButton_2Pushed(app, event)
          
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 0 -app.JogAngle 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end  
        end

        % Button pushed function: RxButton
        function RxButtonPushed(app, event)
          
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 0 app.JogAngle 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end  
        end

        % Button pushed function: RzButton_2
        function RzButton_2Pushed(app, event)
            
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 0 0 0 -app.JogAngle]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);

app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);
            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: RzButton
        function RzButtonPushed(app, event)
         
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 0 0 0 0 app.JogAngle]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end);
app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end   
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
           
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 -app.JogDist 0 0 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end 
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[0 app.JogDist 0 0 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[-app.JogDist 0 0 0 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            
            if(app.Connected)
            g=app.g;

[app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[app.JogDist 0 0 0 0 0]');
[app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
StreamContourData_to_Galil(g,app.hex_path);
app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
app.hex_plot3D=hexapodGraphic_nocalc(app.hex_obj,app.hex_plot3D);

            else
msgbox('Not connected to controller')
            end
        end

        % Button pushed function: SaveworkspaceButton
        function SaveworkspaceButtonPushed(app, event)
            hex_obj=app.hex_obj;
            hex_setup=app.hex_setup;
            hex_path=app.hex_path;

            save_to_base(1);
        end

        % Value changed function: JogDistancemmEditField
        function JogDistancemmEditFieldValueChanged(app, event)
            value = app.JogDistancemmEditField.Value;
            app.JogDist=value/1000;
        end

        % Value changed function: AngleIncrementEditField
        function AngleIncrementEditFieldValueChanged(app, event)
            value = app.AngleIncrementEditField.Value;
            app.JogAngle=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesHex = uiaxes(app.UIFigure);
            title(app.UIAxesHex, 'Current Configuration')
            xlabel(app.UIAxesHex, 'X')
            ylabel(app.UIAxesHex, 'Y')
            zlabel(app.UIAxesHex, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartupTab = uitab(app.TabGroup);
            app.StartupTab.Title = 'Start-up';
            app.InitializeControllerButton = uibutton(app.StartupTab, 'push');
            app.InitializeControllerButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeControllerButtonPushed, true);
            app.InitializeControllerButton.Text = 'Initialize Controller';
            app.ControllerResponseTextAreaLabel = uilabel(app.StartupTab);
            app.ControllerResponseTextAreaLabel.Text = 'Controller Response';
            app.ControllerResponseTextArea = uitextarea(app.StartupTab);
            app.ZButton = uibutton(app.StartupTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.StartupTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '-Z';
            app.ReturntoHomePositionButton = uibutton(app.StartupTab, 'push');
            app.ReturntoHomePositionButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoHomePositionButtonPushed, true);
            app.ReturntoHomePositionButton.Text = 'Return to Home Position';
            app.XButton = uibutton(app.StartupTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.StartupTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.StartupTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.YButton_2 = uibutton(app.StartupTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.RzButton = uibutton(app.StartupTab, 'push');
            app.RzButton.ButtonPushedFcn = createCallbackFcn(app, @RzButtonPushed, true);
            app.RzButton.Text = '+Rz';
            app.RzButton_2 = uibutton(app.StartupTab, 'push');
            app.RzButton_2.ButtonPushedFcn = createCallbackFcn(app, @RzButton_2Pushed, true);
            app.RzButton_2.Text = '-Rz';
            app.RxButton = uibutton(app.StartupTab, 'push');
            app.RxButton.ButtonPushedFcn = createCallbackFcn(app, @RxButtonPushed, true);
            app.RxButton.Text = '+Rx';
            app.RxButton_2 = uibutton(app.StartupTab, 'push');
            app.RxButton_2.ButtonPushedFcn = createCallbackFcn(app, @RxButton_2Pushed, true);
            app.RxButton_2.Text = '-Rx';
            app.RyButton = uibutton(app.StartupTab, 'push');
            app.RyButton.ButtonPushedFcn = createCallbackFcn(app, @RyButtonPushed, true);
            app.RyButton.Text = '+Ry';
            app.RyButton_2 = uibutton(app.StartupTab, 'push');
            app.RyButton_2.ButtonPushedFcn = createCallbackFcn(app, @RyButton_2Pushed, true);
            app.RyButton_2.Text = '-Ry';
            app.JogDistancemmEditFieldLabel = uilabel(app.StartupTab);
            app.JogDistancemmEditFieldLabel.Text = 'Jog Distance (mm)';
            app.JogDistancemmEditField = uieditfield(app.StartupTab, 'numeric');
            app.JogDistancemmEditField.Limits = [0 100];
            app.JogDistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @JogDistancemmEditFieldValueChanged, true);
            app.AngleIncrementEditFieldLabel = uilabel(app.StartupTab);
            app.AngleIncrementEditFieldLabel.Text = 'Angle Increment';
            app.AngleIncrementEditField = uieditfield(app.StartupTab, 'numeric');
            app.AngleIncrementEditField.Limits = [0 10];
            app.AngleIncrementEditField.ValueChangedFcn = createCallbackFcn(app, @AngleIncrementEditFieldValueChanged, true);
            app.HomingTab = uitab(app.TabGroup);
            app.HomingTab.Title = 'Homing';
            app.UIAxes3 = uiaxes(app.HomingTab);
            title(app.UIAxes3, 'Latest Camera Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.GetCurrentPlatformPositionButton = uibutton(app.HomingTab, 'push');
            app.GetCurrentPlatformPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetCurrentPlatformPositionButtonPushed, true);
            app.GetCurrentPlatformPositionButton.Text = {'Get Current '; 'Platform Position'};
            app.HomeGeneratePathButton = uibutton(app.HomingTab, 'push');
            app.HomeGeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @HomeGeneratePathButtonPushed, true);
            app.HomeGeneratePathButton.Text = {'Home '; '(Generate Path)'};
            app.TestArticleSetUpTab = uitab(app.TabGroup);
            app.TestArticleSetUpTab.Title = 'Test Article Set-Up';
            app.TabGroup2 = uitabgroup(app.TestArticleSetUpTab);
            app.CoordinateSystemTab = uitab(app.TabGroup2);
            app.CoordinateSystemTab.Title = 'Coordinate System';
            app.Label = uilabel(app.CoordinateSystemTab);
            app.Label.Text = {'Define the location (in the global coordinates shown below) of the local coordinate frame of the object under test.'; ''; 'All motions will be defined about the local frame.'};
            app.XmmEditFieldLabel = uilabel(app.CoordinateSystemTab);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.CoordinateSystemTab, 'numeric');
            app.YmmEditFieldLabel = uilabel(app.CoordinateSystemTab);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.CoordinateSystemTab, 'numeric');
            app.ZmmEditFieldLabel = uilabel(app.CoordinateSystemTab);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.CoordinateSystemTab, 'numeric');
            app.EnterButton = uibutton(app.CoordinateSystemTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.MassDistributionTab = uitab(app.TabGroup2);
            app.MassDistributionTab.Title = 'Mass Distribution';
            app.I_xxkgm2Label = uilabel(app.MassDistributionTab);
            app.I_xxkgm2Label.Interpreter = 'latex';
            app.I_xxkgm2Label.Text = '$I_{xx}$\ \ \ \ \ \ \ \ \ \ \  kg-m$^2$';
            app.I_xxkgm2EditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.TotalMasskgLabel = uilabel(app.MassDistributionTab);
            app.TotalMasskgLabel.Text = 'Total Mass                   kg';
            app.TotalMasskgEditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.I_yykgm2EditFieldLabel = uilabel(app.MassDistributionTab);
            app.I_yykgm2EditFieldLabel.Interpreter = 'latex';
            app.I_yykgm2EditFieldLabel.Text = '$I_{yy}$\ \ \ \ \ \ \ \ \ \ \  kg-m$^2$';
            app.I_yykgm2EditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.I_zzkgm2EditFieldLabel = uilabel(app.MassDistributionTab);
            app.I_zzkgm2EditFieldLabel.Interpreter = 'latex';
            app.I_zzkgm2EditFieldLabel.Text = '$I_{zz}$\ \ \ \ \ \ \ \ \ \ \  kg-m$^2$';
            app.I_zzkgm2EditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.EnterButton_2 = uibutton(app.MassDistributionTab, 'push');
            app.EnterButton_2.Text = 'Enter';
            app.PlanMotionTab = uitab(app.TabGroup);
            app.PlanMotionTab.Title = 'Plan Motion';
            app.SinusoidalMotionProfileButton = uibutton(app.PlanMotionTab, 'push');
            app.SinusoidalMotionProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SinusoidalMotionProfileButtonPushed, true);
            app.SinusoidalMotionProfileButton.Text = 'Sinusoidal Motion Profile';
            app.PointtoPointMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.PointtoPointMotionButton.ButtonPushedFcn = createCallbackFcn(app, @PointtoPointMotionButtonPushed, true);
            app.PointtoPointMotionButton.Text = 'Point-to-Point Motion';
            app.ImportMotionProfiletxtButton = uibutton(app.PlanMotionTab, 'push');
            app.ImportMotionProfiletxtButton.Text = 'Import Motion Profile (txt)';
            app.SimulateMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.SimulateMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateMotionButtonPushed, true);
            app.SimulateMotionButton.Text = 'Simulate Motion';
            app.AnimateMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.AnimateMotionButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateMotionButtonPushed, true);
            app.AnimateMotionButton.Text = 'Animate Motion';
            app.ExecuteMotionTab = uitab(app.TabGroup);
            app.ExecuteMotionTab.Title = 'Execute Motion';
            app.ExecuteMotionButton = uibutton(app.ExecuteMotionTab, 'push');
            app.ExecuteMotionButton.Text = 'Execute Motion';
            app.HaltMotionButton = uibutton(app.ExecuteMotionTab, 'push');
            app.HaltMotionButton.Text = 'Halt Motion';
            app.DiagnostisticsTab = uitab(app.TabGroup);
            app.DiagnostisticsTab.Title = 'Diagnostistics';
            app.LampPanel = uipanel(app.UIFigure);
            app.LampPanel.Title = 'LampPanel';
            app.QueryControllerStatusButton = uibutton(app.LampPanel, 'push');
            app.QueryControllerStatusButton.Text = 'Query Controller Status';
            app.ControllerInitializedLampLabel = uilabel(app.LampPanel);
            app.ControllerInitializedLampLabel.Text = 'Controller Initialized';
            app.ControllerInitializedLamp = uilamp(app.LampPanel);
            app.PositionKnownLampLabel = uilabel(app.LampPanel);
            app.PositionKnownLampLabel.Text = 'Position Known';
            app.PositionKnownLamp = uilamp(app.LampPanel);
            app.AtHomeLampLabel = uilabel(app.LampPanel);
            app.AtHomeLampLabel.Text = 'At Home';
            app.AtHomeLamp = uilamp(app.LampPanel);
            app.MotionProfileOKLampLabel = uilabel(app.LampPanel);
            app.MotionProfileOKLampLabel.Text = 'Motion Profile OK';
            app.MotionProfileOKLamp = uilamp(app.LampPanel);
            app.ReadyToRunLampLabel = uilabel(app.LampPanel);
            app.ReadyToRunLampLabel.Text = 'Ready To Run';
            app.ReadyToRunLamp = uilamp(app.LampPanel);
            app.AllLimitSwitchesClearLampLabel = uilabel(app.LampPanel);
            app.AllLimitSwitchesClearLampLabel.Text = 'All Limit Switches Clear';
            app.AllLimitSwitchesClearLamp = uilamp(app.LampPanel);
            app.EStopStatusLampLabel = uilabel(app.LampPanel);
            app.EStopStatusLampLabel.Text = 'E-Stop Status';
            app.EStopStatusLamp = uilamp(app.LampPanel);
            app.Ax1Gauge = uigauge(app.UIFigure, 'linear');
            app.Ax1Gauge.Limits = [0 1];
            app.Ax1GaugeLabel = uilabel(app.UIFigure);
            app.Ax1GaugeLabel.Text = 'Ax1';
            app.Ax2Gauge = uigauge(app.UIFigure, 'linear');
            app.Ax2GaugeLabel = uilabel(app.UIFigure);
            app.Ax2GaugeLabel.Text = 'Ax2';
            app.Ax3Gauge = uigauge(app.UIFigure, 'linear');
            app.Ax3GaugeLabel = uilabel(app.UIFigure);
            app.Ax3GaugeLabel.Text = 'Ax3';
            app.Ax4Gauge = uigauge(app.UIFigure, 'linear');
            app.Ax4GaugeLabel = uilabel(app.UIFigure);
            app.Ax4GaugeLabel.Text = 'Ax4';
            app.Ax5Gauge = uigauge(app.UIFigure, 'linear');
            app.Ax5GaugeLabel = uilabel(app.UIFigure);
            app.Ax5GaugeLabel.Text = 'Ax5';
            app.Ax6Gauge = uigauge(app.UIFigure, 'linear');
            app.Ax6GaugeLabel = uilabel(app.UIFigure);
            app.Ax6GaugeLabel.Text = 'Ax6';
            app.SaveworkspaceButton = uibutton(app.UIFigure, 'push');
            app.SaveworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveworkspaceButtonPushed, true);
            app.SaveworkspaceButton.Text = 'Save workspace';
            app.XmLabel = uilabel(app.UIFigure);
            app.XmLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmEditField.Editable = 'off';
            app.YmEditFieldLabel = uilabel(app.UIFigure);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmEditField.Editable = 'off';
            app.ZmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmEditFieldLabel.Text = 'Z (m)';
            app.ZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmEditField.Editable = 'off';
            app.RolldegEditFieldLabel = uilabel(app.UIFigure);
            app.RolldegEditFieldLabel.Text = 'Roll (deg)';
            app.RolldegEditField = uieditfield(app.UIFigure, 'numeric');
            app.RolldegEditField.Editable = 'off';
            app.PitchdegEditFieldLabel = uilabel(app.UIFigure);
            app.PitchdegEditFieldLabel.Text = 'Pitch (deg)';
            app.PitchdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchdegEditField.Editable = 'off';
            app.YawdegEditFieldLabel = uilabel(app.UIFigure);
            app.YawdegEditFieldLabel.Text = 'Yaw (deg)';
            app.YawdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawdegEditField.Editable = 'off';
            app.AxNumeric_1 = uieditfield(app.UIFigure, 'numeric');
            app.AxNumeric_1.Editable = 'off';
            app.AxNumeric_2 = uieditfield(app.UIFigure, 'numeric');
            app.AxNumeric_2.Editable = 'off';
            app.AxNumeric_3 = uieditfield(app.UIFigure, 'numeric');
            app.AxNumeric_3.Editable = 'off';
            app.AxNumeric_4 = uieditfield(app.UIFigure, 'numeric');
            app.AxNumeric_4.Editable = 'off';
            app.AxNumeric_5 = uieditfield(app.UIFigure, 'numeric');
            app.AxNumeric_5.Editable = 'off';
            app.AxNumeric_6 = uieditfield(app.UIFigure, 'numeric');
            app.AxNumeric_6.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HexControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end