classdef Hexapod_Generate_MultiSine_Excitation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NumberofRealiationsMSpinner     matlab.ui.control.Spinner
        NumberofRealiationsMSpinnerLabel  matlab.ui.control.Label
        NumberofPeriodsPSpinner         matlab.ui.control.Spinner
        NumberofPeriodsPSpinnerLabel    matlab.ui.control.Label
        MaximumFrequencyHzSpinner       matlab.ui.control.Spinner
        MaximumFrequencyHzSpinnerLabel  matlab.ui.control.Label
        GenerateMultisineButton         matlab.ui.control.Button
        MinimumFrequencyHzSpinner       matlab.ui.control.Spinner
        MinimumFrequencyLabel           matlab.ui.control.Label
        ExprimentalFrequencyBandHzSlider  matlab.ui.control.RangeSlider
        ExprimentalFrequencyBandHzSliderLabel  matlab.ui.control.Label
        SampletimeforoneperiodEditField  matlab.ui.control.NumericEditField
        SampletimeforoneperiodEditFieldLabel  matlab.ui.control.Label
        SamplingRateFsEditField         matlab.ui.control.NumericEditField
        SamplingRateFsEditFieldLabel    matlab.ui.control.Label
        NumberofInputsNuEditField       matlab.ui.control.NumericEditField
        NumberofInputsNuLabel           matlab.ui.control.Label
        SelectAllCheckBox               matlab.ui.control.CheckBox
        YawCheckBox                     matlab.ui.control.CheckBox
        PitchCheckBox                   matlab.ui.control.CheckBox
        RollCheckBox                    matlab.ui.control.CheckBox
        ZCheckBox                       matlab.ui.control.CheckBox
        YCheckBox                       matlab.ui.control.CheckBox
        XCheckBox                       matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        progressBarAxes                 matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ChooseFolderEditField           matlab.ui.control.EditField
        ChooseFolderEditFieldLabel      matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        nu = 0;                           % the number of inputs to simulate
        T_end = 300;                      % the number of seconds in one period of the experiment
        Fs = 2000;                        % the sampling rate of the experiments in Hz
        MaxFreq = 5;                      % the maximum frequency of the band of interest in Hz
        MinFreq = 0.01;                   % the minimum frequency of the band of interest in Hz
        minFreqRange = 0.1;               % the minimum frequency range of the band of interes in Hz
        Periods = 2;                      % the number of periods in one experiment
        Realizations = 2;                 % the number of realizations in one estimate of the FRF
        activeInputs = [];                % the active inputs for the multisine to populate
        data =  struct('dummyField',[]);  % the structure where data is stored
    end
    
    methods (Access = private)
          
        function updateActiveCheckboxes(app)
            app.activeInputs = [app.XCheckBox.Value,app.YCheckBox.Value,...
                            app.ZCheckBox.Value,app.RollCheckBox.Value,...
                            app.PitchCheckBox.Value,app.YawCheckBox.Value];
            activeCount = sum(app.activeInputs);
            app.NumberofInputsNuEditField.Value = activeCount;
            app.nu = activeCount;
        end
        
        % initialize the progress bar
        function initProgressBar(app)
            cla(app.progressBarAxes);
            barh(app.progressBarAxes, 0, 'FaceColor', 'blue');
            app.progressBarAxes.YLim = [0.5 1.5];
            app.progressBarAxes.XLim = [0 1];
            app.progressBarAxes.YTick = [];
            app.progressBarAxes.XTick = [];
            title(app.progressBarAxes, 'Generating Multisine...');
        end
        
        function updateProgressBar(app,progress)
            % Ensure progress is between 0 and 1
            progress = max(0,min(progress,1));
            barh(app.progressBarAxes, progress,'FaceColor','blue');
            app.progressBarAxes.YLim = [0.5 1.5];
            app.progressBarAxes.XLim = [0 1];
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MinimumFrequencyHzSpinner.Step = app.minFreqRange;
            app.MaximumFrequencyHzSpinner.Step = app.minFreqRange;
            % set default values
            % input DOF
            app.SelectAllCheckBox.Value = 1;
            SelectAllCheckBoxValueChanged(app);
            % sampling time
            app.SamplingRateFsEditField.Value = 2000;
            SamplingRateFsEditFieldValueChanged(app);
            % time for one period
            app.SampletimeforoneperiodEditField.Value = 300;
            app.SampletimeforoneperiodEditFieldValueChanged(app);
            % Min and Max Frequency
            app.MinimumFrequencyHzSpinner.Value = 0.01;
            app.MaximumFrequencyHzSpinner.Value = 5;
            % Number of Periods
            app.NumberofPeriodsPSpinner.Value = 2;
            app.NumberofPeriodsPSpinnerValueChanged(app);
            % Number of Realizations
            app.NumberofRealiationsMSpinner.Value = 2;
            app.NumberofRealiationsMSpinnerValueChanged(app);
            % progress bar
            %initProgressBar(app);
        end

        % Button pushed function: GenerateMultisineButton
        function GenerateMultisineButtonPushed(app, event)
            % Validate that at least one DOF is selected
            if app.nu == 0
                uialert(app.UIFigure,'Please select at least on input.',...
                    'Input Error');
            end
            % init progress bar
            initProgressBar(app);
            % Inputs are valid, calculate Multisine
            Ts = 1/app.Fs;
            N = app.T_end/Ts+1;
            ExcitedHarm = round((app.MinFreq*N)/app.Fs:1:(2*app.MaxFreq*N)/app.Fs); % 0.01 - MaxFreq (hz)
            nh = length(ExcitedHarm);
            % define amplitude spectra inputs
            AmplitudeExcitedHarm = ones(app.nu, nh);
            AmplitudeExcitedHarm(:, floor(nh/2):end) = 1e-5; % relative amplitudes
            if app.nu == 1
                % special case, use SISO multisine generator
                for i = 1:app.Realizations
                    r = CalcMultisine(ExcitedHarm,N,AmplitudeExcitedHarm);
                    theSignal = repmat(r,[1,1,app.Periods]);
                    
                    % update the progress bar
                    updateProgressBar(app, i / app.Realizations);

                    % init our 6 x 1 x N*P input signal and reference
                    theExcitation = zeros(6,app.nu,length(theSignal));
                    theReference = zeros(6,app.nu,N);
                    % identify which DOF and package the signal
                    activeDOFIndices = find(app.activeInputs);
                    for DOF = 1:activeDOFIndices
                        dofIndex = activeDOFIndices(DOF);
                        theExcitation(dofIndex,:,:) = theSignal;
                        theReference(dofIndex,:,:) = r;
                    end
                    % package theExcitation
                    theRealization = strcat('R',string(i));
                    app.data.u.E1.(theRealization) = squeeze(theExcitation);
                    app.data.r.E1.(theRealization)(:,:,1:N) = theReference;
                end
                updateProgressBar(app, 1);
                title(app.progressBarAxes,'Finished');
            else
                % general case
                nsteps = app.Realizations * app.nu;
                count = 0;
                for i = 1:app.Realizations
                    r = Calc_MIMO_Multisine(ExcitedHarm,N,AmplitudeExcitedHarm);
                    theSignal = repmat(r,[1,1,app.Periods]);

                    % init our 6 x 1 x N*P input signal and reference
                    theExcitation = zeros(6,app.nu,length(theSignal));
                    theReference = zeros(6,app.nu,N);
                    % identify which DOF and package the signal
                    activeDOFIndices = find(app.activeInputs);
                    for DOF = 1:activeDOFIndices
                        dofIndex = activeDOFIndices(DOF);
                        theExcitation(dofIndex,:,:) = theSignal(DOF,:,:);
                        theReference(dofIndex,:,:) = r(DOF,:,:);
                    end
                    % Seperate each experiment
                    for j = 1:app.nu
                        theRealization = strcat('R',string(i));
                        theExperiment = strcat('E',string(j));
                        app.data.u.(theExperiment).(theRealization) = squeeze(theExcitation(:,j,:));
                        app.data.r.(theExperiment).(theRealization)(:,:,1:N) = theReference(:,j,1:N); 

                        %update the progress bar
                        count = 1 + count;
                        updateProgressBar(app, count/nsteps)
                    end
                end
                updateProgressBar(app, 1);
                title(app.progressBarAxes,'Finished');
            end
            
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.XCheckBox.Value;
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.YCheckBox.Value;
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.ZCheckBox.Value;
        end

        % Value changed function: RollCheckBox
        function RollCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.RollCheckBox.Value;
        end

        % Value changed function: PitchCheckBox
        function PitchCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.PitchCheckBox.Value;
        end

        % Value changed function: YawCheckBox
        function YawCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.YawCheckBox.Value;
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            % set all check boxes to checked
            app.XCheckBox.Value = value;
            app.YCheckBox.Value = value;
            app.ZCheckBox.Value = value;
            app.RollCheckBox.Value = value;
            app.PitchCheckBox.Value = value;
            app.YawCheckBox.Value = value;
            % update the active checkboxes
            updateActiveCheckboxes(app);
        end

        % Value changed function: SamplingRateFsEditField
        function SamplingRateFsEditFieldValueChanged(app, event)
            if mod(app.SamplingRateFsEditField.Value, 1) ~= 0
                uialert(app.UIFigure,'Sampling rate must be an integer.',...
                    'Input Error','Icon','warning');
                app.SamplingRateFsEditField.Value = round(app.SamplingRateFsEditField.Value);
            end
            app.Fs = app.SamplingRateFsEditField.Value;
            
        end

        % Value changed function: SampletimeforoneperiodEditField
        function SampletimeforoneperiodEditFieldValueChanged(app, event)
            app.T_end = app.SampletimeforoneperiodEditField.Value;
            
        end

        % Value changing function: ExprimentalFrequencyBandHzSlider
        function ExprimentalFrequencyBandHzSliderValueChanging(app, event)
            sliderValue = event.Value;
            if sliderValue(1) < 0.1
                % we can't be exactly on 0, min tested is 0.1 Hz
                sliderValue(1) = 0.1;
            end
            app.MinimumFrequencyHzSpinner.Value = sliderValue(1);
            app.MaximumFrequencyHzSpinner.Value = sliderValue(2);
        end

        % Value changing function: MinimumFrequencyHzSpinner
        function MinimumFrequencyHzSpinnerValueChanging(app, event)
            minFreq = event.Value;
            maxFreq = app.MaximumFrequencyHzSpinner.Value;
            if (maxFreq - minFreq) < app.minFreqRange
                errorMessage = sprintf('Minimum range between frequencies must be at least %.2f.',app.minFreqRange);
                uialert(app.UIFigure, errorMessage, 'Range Error','Icon','error');
                maxFreq = minFreq + app.minFreqRange;
                app.MaximumFrequencyHzSpinner.Value = maxFreq;
                app.MaxFreq = maxFreq;
            end
            app.MinFreq = minFreq;
            app.ExprimentalFrequencyBandHzSlider.Value = [app.MinFreq, app.MaxFreq];
        end

        % Value changing function: MaximumFrequencyHzSpinner
        function MaximumFrequencyHzSpinnerValueChanging(app, event)
            maxFreq = event.Value;
            minFreq = app.MinimumFrequencyHzSpinner.Value;
            if (maxFreq - minFreq) < app.minFreqRange
                errorMessage = sprintf('Minimum range between frequencies must be at least %.2f.',app.minFreqRange);
                uialert(app.UIFigure, errorMessage, 'Range Error','Icon','warning');
                minFreq = maxFreq - app.minFreqRange;
                if minFreq < app.MinimumFrequencyHzSpinner.Limits(1)
                    minfreq = app.MinimumFrequencyHzSpinner.Limits(1);
                    maxFreq = minFreq + app.minFreqRange;
                end
                app.MinimumFrequencyHzSpinner.Value = minFreq;
                app.MinFreq = minFreq;
            end
            app.MaxFreq = maxFreq;
            app.ExprimentalFrequencyBandHzSlider.Value = [app.MinFreq,app.MaxFreq];
        end

        % Value changed function: NumberofPeriodsPSpinner
        function NumberofPeriodsPSpinnerValueChanged(app, event)
            if mod(app.NumberofPeriodsPSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Number of Periods must be an integer',...
                    'Input Error','Icon','warning');
                P = round(app.NumberofPeriodsPSpinner.Value); % must be an int
                app.NumberofPeriodsPSpinner.Value = P;
            end
            app.Periods = app.NumberofPeriodsPSpinner.Value;
        end

        % Value changed function: NumberofRealiationsMSpinner
        function NumberofRealiationsMSpinnerValueChanged(app, event)
            if mod(app.NumberofRealiationsMSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Number of Realizations must be an integer',...
                    'Input Error','Icon','warning');
                M = round(app.NumberofRealiationsMSpinner.Value); % must be int
                app.NumberofRealiationsMSpinner.Value = M;
            end
            app.Realizations = app.NumberofRealiationsMSpinner.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {684, 684};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {499, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.progressBarAxes = uiaxes(app.LeftPanel);
            title(app.progressBarAxes, 'Progress Bar')
            app.progressBarAxes.XTick = [];
            app.progressBarAxes.XTickLabel = '';
            app.progressBarAxes.YTick = [];
            app.progressBarAxes.YTickLabel = '';
            app.progressBarAxes.ZTick = [0 0.5 1];
            app.progressBarAxes.ZTickLabel = '';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Hexapod Multisine Generator'};
            app.XCheckBox = uicheckbox(app.LeftPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Text = 'X';
            app.YCheckBox = uicheckbox(app.LeftPanel);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Text = 'Y';
            app.ZCheckBox = uicheckbox(app.LeftPanel);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.RollCheckBox = uicheckbox(app.LeftPanel);
            app.RollCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.RollCheckBox.Text = 'Roll';
            app.PitchCheckBox = uicheckbox(app.LeftPanel);
            app.PitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @PitchCheckBoxValueChanged, true);
            app.PitchCheckBox.Text = 'Pitch';
            app.YawCheckBox = uicheckbox(app.LeftPanel);
            app.YawCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawCheckBoxValueChanged, true);
            app.YawCheckBox.Text = 'Yaw';
            app.SelectAllCheckBox = uicheckbox(app.LeftPanel);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.NumberofInputsNuLabel = uilabel(app.LeftPanel);
            app.NumberofInputsNuLabel.Text = 'Number of Inputs, Nu';
            app.NumberofInputsNuEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofInputsNuEditField.Limits = [0 6];
            app.NumberofInputsNuEditField.Editable = 'off';
            app.SamplingRateFsEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRateFsEditFieldLabel.Text = 'Sampling Rate, Fs';
            app.SamplingRateFsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRateFsEditField.Limits = [100 10000];
            app.SamplingRateFsEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateFsEditFieldValueChanged, true);
            app.SamplingRateFsEditField.Value = 2000;
            app.SampletimeforoneperiodEditFieldLabel = uilabel(app.LeftPanel);
            app.SampletimeforoneperiodEditFieldLabel.Text = 'Sample time for one period ';
            app.SampletimeforoneperiodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SampletimeforoneperiodEditField.Limits = [300 3600];
            app.SampletimeforoneperiodEditField.ValueChangedFcn = createCallbackFcn(app, @SampletimeforoneperiodEditFieldValueChanged, true);
            app.SampletimeforoneperiodEditField.Value = 300;
            app.ExprimentalFrequencyBandHzSliderLabel = uilabel(app.LeftPanel);
            app.ExprimentalFrequencyBandHzSliderLabel.Text = {'Exprimental '; 'Frequency Band (Hz) '};
            app.ExprimentalFrequencyBandHzSlider = uislider(app.LeftPanel, 'range');
            app.ExprimentalFrequencyBandHzSlider.Limits = [0 5];
            app.ExprimentalFrequencyBandHzSlider.ValueChangingFcn = createCallbackFcn(app, @ExprimentalFrequencyBandHzSliderValueChanging, true);
            app.ExprimentalFrequencyBandHzSlider.Value = [0 5];
            app.MinimumFrequencyLabel = uilabel(app.LeftPanel);
            app.MinimumFrequencyLabel.Text = {'Minimum '; 'Frequency (Hz)'};
            app.MinimumFrequencyHzSpinner = uispinner(app.LeftPanel);
            app.MinimumFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @MinimumFrequencyHzSpinnerValueChanging, true);
            app.MinimumFrequencyHzSpinner.Limits = [0.01 5];
            app.MinimumFrequencyHzSpinner.Value = 0.1;
            app.GenerateMultisineButton = uibutton(app.LeftPanel, 'push');
            app.GenerateMultisineButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMultisineButtonPushed, true);
            app.GenerateMultisineButton.Text = 'Generate Multisine';
            app.MaximumFrequencyHzSpinnerLabel = uilabel(app.LeftPanel);
            app.MaximumFrequencyHzSpinnerLabel.Text = {'Maximum'; 'Frequency (Hz)'};
            app.MaximumFrequencyHzSpinner = uispinner(app.LeftPanel);
            app.MaximumFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @MaximumFrequencyHzSpinnerValueChanging, true);
            app.MaximumFrequencyHzSpinner.Limits = [0.2 5];
            app.MaximumFrequencyHzSpinner.Value = 5;
            app.NumberofPeriodsPSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofPeriodsPSpinnerLabel.Text = 'Number o f Periods, P';
            app.NumberofPeriodsPSpinner = uispinner(app.LeftPanel);
            app.NumberofPeriodsPSpinner.Limits = [2 10];
            app.NumberofPeriodsPSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPeriodsPSpinnerValueChanged, true);
            app.NumberofPeriodsPSpinner.Value = 2;
            app.NumberofRealiationsMSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofRealiationsMSpinnerLabel.Text = 'Number of Realiations, M';
            app.NumberofRealiationsMSpinner = uispinner(app.LeftPanel);
            app.NumberofRealiationsMSpinner.Limits = [2 4];
            app.NumberofRealiationsMSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofRealiationsMSpinnerValueChanged, true);
            app.NumberofRealiationsMSpinner.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.RightPanel, 'push');
            app.ChooseFolderEditFieldLabel = uilabel(app.RightPanel);
            app.ChooseFolderEditFieldLabel.Text = 'Choose Folder';
            app.ChooseFolderEditField = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hexapod_Generate_MultiSine_Excitation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end